classdef CVModelGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AVMaxOpeningAreacm2EditFieldLabel  matlab.ui.control.Label
        AVMaxOpeningAreacm2EditField    matlab.ui.control.NumericEditField
        ApplyButton                     matlab.ui.control.Button
        AVLeakageAreacm2EditFieldLabel  matlab.ui.control.Label
        AVLeakageAreacm2EditField       matlab.ui.control.NumericEditField
        MVMaxOpeningAreacm2EditFieldLabel  matlab.ui.control.Label
        MVMaxOpeningAreacm2EditField    matlab.ui.control.NumericEditField
        MVLeakageAreacm2EditFieldLabel  matlab.ui.control.Label
        MVLeakageAreacm2EditField       matlab.ui.control.NumericEditField
        ResetDefaultValuesButton        matlab.ui.control.Button
        PAo_maxTextAreaLabel            matlab.ui.control.Label
        PAo_maxTextArea                 matlab.ui.control.TextArea
        PAo_minLabel                    matlab.ui.control.Label
        PAo_minTextArea                 matlab.ui.control.TextArea
        PleaseEnterModelNameEditFieldLabel  matlab.ui.control.Label
        PleaseEnterModelNameEditField   matlab.ui.control.EditField
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: ApplyButton, PAo_maxTextArea, 
        % PAo_minTextArea
        function ApplyButtonPushed(app, event)
            modelname = app.PleaseEnterModelNameEditField.Value;
            set_param(append(modelname,'/Left Heart/AV_MV_IL/Aortic Valve'),...
               'area_max', num2str(app.AVMaxOpeningAreacm2EditField.Value))
            set_param(append(modelname,'/Left Heart/AV_MV_IL/Aortic Valve'),...
               'area_leak', num2str(app.AVLeakageAreacm2EditField.Value))
            set_param(append(modelname,'/Left Heart/AV_MV_IL/Mitral Valve'),...
               'area_max', num2str(app.MVMaxOpeningAreacm2EditField.Value))
            set_param(append(modelname,'/Left Heart/AV_MV_IL/Mitral Valve'),...
               'area_leak', num2str(app.MVLeakageAreacm2EditField.Value))
            simout = sim(modelname);
            
            max_PAo = PAo_max;
            app.PAo_maxTextArea.Value = num2str(max_PAo);
            
            min_PAo = PAo_min;
            app.PAo_minTextArea.Value = num2str(min_PAo);
        end

        % Button pushed function: ResetDefaultValuesButton
        function ResetDefaultValuesButtonPushed(app, event)
            app.AVMaxOpeningAreacm2EditField.Value = 4;
            app.AVLeakageAreacm2EditField.Value = 1e-13;
            app.MVMaxOpeningAreacm2EditField.Value = 5;
            app.MVLeakageAreacm2EditField.Value = 1e-13;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AVMaxOpeningAreacm2EditFieldLabel = uilabel(app.UIFigure);
            app.AVMaxOpeningAreacm2EditFieldLabel.Text = 'AV Max Opening Area (cm^2)';
            app.AVMaxOpeningAreacm2EditField = uieditfield(app.UIFigure, 'numeric');
            app.AVMaxOpeningAreacm2EditField.Value = 4;
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.AVLeakageAreacm2EditFieldLabel = uilabel(app.UIFigure);
            app.AVLeakageAreacm2EditFieldLabel.Text = 'AV Leakage Area (cm^2)';
            app.AVLeakageAreacm2EditField = uieditfield(app.UIFigure, 'numeric');
            app.AVLeakageAreacm2EditField.Value = 1e-13;
            app.MVMaxOpeningAreacm2EditFieldLabel = uilabel(app.UIFigure);
            app.MVMaxOpeningAreacm2EditFieldLabel.Text = 'MV Max Opening Area (cm^2)';
            app.MVMaxOpeningAreacm2EditField = uieditfield(app.UIFigure, 'numeric');
            app.MVMaxOpeningAreacm2EditField.Value = 5;
            app.MVLeakageAreacm2EditFieldLabel = uilabel(app.UIFigure);
            app.MVLeakageAreacm2EditFieldLabel.Text = 'MV Leakage Area (cm^2)';
            app.MVLeakageAreacm2EditField = uieditfield(app.UIFigure, 'numeric');
            app.MVLeakageAreacm2EditField.Value = 1e-13;
            app.ResetDefaultValuesButton = uibutton(app.UIFigure, 'push');
            app.ResetDefaultValuesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetDefaultValuesButtonPushed, true);
            app.ResetDefaultValuesButton.Text = 'Reset Default Values';
            app.PAo_maxTextAreaLabel = uilabel(app.UIFigure);
            app.PAo_maxTextAreaLabel.Text = 'PAo_max';
            app.PAo_maxTextArea = uitextarea(app.UIFigure);
            app.PAo_maxTextArea.ValueChangedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.PAo_minLabel = uilabel(app.UIFigure);
            app.PAo_minLabel.Text = 'PAo_min ';
            app.PAo_minTextArea = uitextarea(app.UIFigure);
            app.PAo_minTextArea.ValueChangedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.PleaseEnterModelNameEditFieldLabel = uilabel(app.UIFigure);
            app.PleaseEnterModelNameEditFieldLabel.Text = 'Please Enter Model Name:';
            app.PleaseEnterModelNameEditField = uieditfield(app.UIFigure, 'text');
            app.PleaseEnterModelNameEditField.Value = 'Michaels_CVModel_0408_fast4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CVModelGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end